classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioEqualizerUIFigure         matlab.ui.Figure
        FilterTypeDropDownLabel        matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        BrowseButton                   matlab.ui.control.Button
        SaveOutputButton               matlab.ui.control.Button
        OutputSamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        OutputSamplingFrequencyEditField  matlab.ui.control.NumericEditField
        PlayStopInputButton            matlab.ui.control.Button
        PlayStopOutputButton           matlab.ui.control.Button
        HzLabel_7                      matlab.ui.control.Label
        HzSlider                       matlab.ui.control.Slider
        HzLabel                        matlab.ui.control.Label
        HzSlider_2                     matlab.ui.control.Slider
        KHzLabel                       matlab.ui.control.Label
        KHzSlider_11                   matlab.ui.control.Slider
        HzSlider_3Label                matlab.ui.control.Label
        HzSlider_3                     matlab.ui.control.Slider
        KHzLabel_2                     matlab.ui.control.Label
        KHzSlider_13                   matlab.ui.control.Slider
        KHzLabel_3                     matlab.ui.control.Label
        KHzSlider_14                   matlab.ui.control.Slider
        KHzLabel_4                     matlab.ui.control.Label
        KHzSlider_15                   matlab.ui.control.Slider
        KHzLabel_5                     matlab.ui.control.Label
        KHzSlider_16                   matlab.ui.control.Slider
        KHzLabel_6                     matlab.ui.control.Label
        KHzSlider_17                   matlab.ui.control.Slider
        TabGroup                       matlab.ui.container.TabGroup
        OutputResponseTab              matlab.ui.container.Tab
        OutputTimeRespAxes             matlab.ui.control.UIAxes
        OutputFreqRespMagAxes          matlab.ui.control.UIAxes
        OutputFreqRespPhaseAxes        matlab.ui.control.UIAxes
        PerBandResponseTab             matlab.ui.container.Tab
        BandDropDownLabel              matlab.ui.control.Label
        PerBandRespBandSelector        matlab.ui.control.DropDown
        PerBandTimeRespAxes            matlab.ui.control.UIAxes
        PerBandFreqRespMagAxes         matlab.ui.control.UIAxes
        PerBandFreqRespPhaseAxes       matlab.ui.control.UIAxes
        InputResponseTab               matlab.ui.container.Tab
        InputTimeRespAxes              matlab.ui.control.UIAxes
        InputFreqRespMagAxes           matlab.ui.control.UIAxes
        InputFreqRespPhaseAxes         matlab.ui.control.UIAxes
        ApplyChangesButton             matlab.ui.control.Button
        InputSamplingFrequencyLabel    matlab.ui.control.Label
        InputSamplingFrequencyValueLabel  matlab.ui.control.Label
        OutputSampleRateDropDownLabel  matlab.ui.control.Label
        OutputSampleRateDropDown       matlab.ui.control.DropDown
        LoadingLabel                   matlab.ui.control.Label
    end


    %Constants
    properties (Access = private)
        FIR_HAMMING_S = "FIR Hamming";
        FIR_RECTANGULAR_S = "FIR Rectangular";
        FIR_EQUIRIPPLE_S = "FIR Equiripple";
        FIR_LEAST_SQUARE_S = "FIR Least Square";
        IIR_BUTTERWORTH_S = "IIR Butterworth";
        
        FIR_HAMMING = 0;
        FIR_RECTANGULAR = 1;
        FIR_EQUIRIPPLE = 2;
        FIR_LEAST_SQUARE = 3;
        IIR_BUTTERWORTH = 4;
        
        BAND_RANGE_0_170 = 1;
        BAND_RANGE_170_310 = 2;
        BAND_RANGE_310_600 = 3;
        BAND_RANGE_600_1k = 4;
        BAND_RANGE_1k_3k = 5;
        BAND_RANGE_3k_6k = 6;
        BAND_RANGE_6k_12k = 7;
        BAND_RANGE_12k_14k = 8;
        BAND_RANGE_14k_16k = 9;
        
        BAND_RANGE_0_170_S = "0 - 170 Hz";
        BAND_RANGE_170_310_S = "170 - 310 Hz";
        BAND_RANGE_310_600_S = "310 - 600 Hz";
        BAND_RANGE_600_1k_S = "600 - 1000 Hz";
        BAND_RANGE_1k_3k_S = "1 - 3 KHz";
        BAND_RANGE_3k_6k_S = "3 - 6 KHz";
        BAND_RANGE_6k_12k_S = "6 - 12 KHz";
        BAND_RANGE_12k_14k_S = "12 - 14 KHz";
        BAND_RANGE_14k_16k_S = "14 - 16 KHz";
        
        OUTPUT_SAMPLE_NORMAL_S = "Normal";
        OUTPUT_SAMPLE_HALVED_S = "Halved";
        OUTPUT_SAMPLE_DOUBLED_S = "Doubled";
        
        OUTPUT_SAMPLE_NORMAL = 1;
        OUTPUT_SAMPLE_HALVED = 2;
        OUTPUT_SAMPLE_DOUBLED = 3;
    end    
    
    %Local Variables
    properties (Access = private)
        gains = zeros(1, 9)
        
        soundPlaying = 0;
        inputFilePath =  "./.";
        inputFileName
        inputFreq
        inputAudio
        
        outputFilePath = "./.";
        outputFileName
        
        outputFreq = 44000;
        outputAudio
        
        outputSample = 1
        
        currentFilterType = 1;
        
        perBandGraphBandRange = 1;
        
        filterBand_0_170
        filterBand_170_310
        filterBand_310_600
        filterBand_600_1k
        filterBand_1k_3k
        filterBand_3k_6k
        filterBand_6k_12k
        filterBand_12k_14k
        filterBand_14k_16k
        
        outputBand_0_170
        outputBand_170_310
        outputBand_310_600
        outputBand_600_1k
        outputBand_1k_3k
        outputBand_3k_6k
        outputBand_6k_12k
        outputBand_12k_14k
        outputBand_14k_16k
    end

    methods (Access = private)
    
        function createFilters(app)
            switch app.currentFilterType
                
                case app.FIR_HAMMING
                    
                    app.filterBand_0_170 = firHamming(app.outputFreq, 10, 170);
                    app.filterBand_170_310 =  firHamming(app.outputFreq, 170, 310); 
                    app.filterBand_310_600 = firHamming(app.outputFreq, 310, 600);
                    app.filterBand_600_1k = firHamming(app.outputFreq, 600, 1000);
                    app.filterBand_1k_3k = firHamming(app.outputFreq, 1000, 3000);
                    app.filterBand_3k_6k = firHamming(app.outputFreq, 3000, 6000);
                    app.filterBand_6k_12k = firHamming(app.outputFreq, 6000, 12000);
                    app.filterBand_12k_14k = firHamming(app.outputFreq, 12000, 14000);
                    app.filterBand_14k_16k = firHamming(app.outputFreq, 14000, 16000);
                    
                case app.FIR_RECTANGULAR
                    
                    app.filterBand_0_170 = firRectangular(app.outputFreq, 10, 170);
                    app.filterBand_170_310 =  firRectangular(app.outputFreq, 170, 310); 
                    app.filterBand_310_600 = firRectangular(app.outputFreq, 310, 600);
                    app.filterBand_600_1k = firRectangular(app.outputFreq, 600, 1000);
                    app.filterBand_1k_3k = firRectangular(app.outputFreq, 1000, 3000);
                    app.filterBand_3k_6k = firRectangular(app.outputFreq, 3000, 6000);
                        app.filterBand_6k_12k = firRectangular(app.outputFreq, 6000, 12000);
                    app.filterBand_12k_14k = firRectangular(app.outputFreq, 12000, 14000);
                    app.filterBand_14k_16k = firRectangular(app.outputFreq, 14000, 16000);
                    
                case app.FIR_EQUIRIPPLE
                    
                    app.filterBand_0_170 = firEquirriple(app.outputFreq, 10, 170);
                    app.filterBand_170_310 =  firEquirriple(app.outputFreq, 170, 310); 
                    app.filterBand_310_600 = firEquirriple(app.outputFreq, 310, 600);
                    app.filterBand_600_1k = firEquirriple(app.outputFreq, 600, 1000);
                    app.filterBand_1k_3k = firEquirriple(app.outputFreq, 1000, 3000);
                    app.filterBand_3k_6k = firEquirriple(app.outputFreq, 3000, 6000);
                    app.filterBand_6k_12k = firEquirriple(app.outputFreq, 6000, 12000);
                    app.filterBand_12k_14k = firEquirriple(app.outputFreq, 12000, 14000);
                    app.filterBand_14k_16k = firEquirriple(app.outputFreq, 14000, 16000);
                    
                case app.FIR_LEAST_SQUARE
                    
                    app.filterBand_0_170 = firLS(app.outputFreq, 10, 170);
                    app.filterBand_170_310 =  firLS(app.outputFreq, 170, 310); 
                    app.filterBand_310_600 = firLS(app.outputFreq, 310, 600);
                    app.filterBand_600_1k = firLS(app.outputFreq, 600, 1000);
                    app.filterBand_1k_3k = firLS(app.outputFreq, 1000, 3000);
                    app.filterBand_3k_6k = firLS(app.outputFreq, 3000, 6000);
                    app.filterBand_6k_12k = firLS(app.outputFreq, 6000, 12000);
                    app.filterBand_12k_14k = firLS(app.outputFreq, 12000, 14000);
                    app.filterBand_14k_16k = firLS(app.outputFreq, 14000, 16000);
                    
                case app.IIR_BUTTERWORTH
                    
                    app.filterBand_0_170 = iirButter(app.outputFreq, 10, 170);
                    app.filterBand_170_310 =  iirButter(app.outputFreq, 170, 310); 
                    app.filterBand_310_600 = iirButter(app.outputFreq, 310, 600);
                    app.filterBand_600_1k = iirButter(app.outputFreq, 600, 1000);
                    app.filterBand_1k_3k = iirButter(app.outputFreq, 1000, 3000);
                    app.filterBand_3k_6k = iirButter(app.outputFreq, 3000, 6000);
                    app.filterBand_6k_12k = iirButter(app.outputFreq, 6000, 12000);
                    app.filterBand_12k_14k = iirButter(app.outputFreq, 12000, 14000);
                    app.filterBand_14k_16k = iirButter(app.outputFreq, 14000, 16000);
                    
            end
        end
        
        function applyFilters(app)
            app.outputBand_0_170 = filter(app.filterBand_0_170, app.inputAudio);
            app.outputBand_170_310 = filter(app.filterBand_170_310, app.inputAudio);
            app.outputBand_310_600 = filter(app.filterBand_310_600, app.inputAudio);
            app.outputBand_600_1k = filter(app.filterBand_600_1k, app.inputAudio);
            app.outputBand_1k_3k = filter(app.filterBand_1k_3k, app.inputAudio);
            app.outputBand_3k_6k = filter(app.filterBand_3k_6k, app.inputAudio);
            app.outputBand_6k_12k = filter(app.filterBand_6k_12k, app.inputAudio);
            app.outputBand_12k_14k = filter(app.filterBand_12k_14k, app.inputAudio);
            app.outputBand_14k_16k = filter(app.filterBand_14k_16k, app.inputAudio);
            
            
            app.outputAudio = app.outputBand_0_170 * db2mag(app.gains(app.BAND_RANGE_0_170))
                              + app.outputBand_170_310 * db2mag(app.gains(app.BAND_RANGE_170_310));
                              + app.outputBand_310_600 * db2mag(app.gains(app.BAND_RANGE_310_600))
                              + app.outputBand_600_1k * db2mag(app.gains(app.BAND_RANGE_600_1k))
                              + app.outputBand_1k_3k * db2mag(app.gains(app.BAND_RANGE_1k_3k))
                              + app.outputBand_3k_6k * db2mag(app.gains(app.BAND_RANGE_3k_6k))
                              + app.outputBand_6k_12k * db2mag(app.gains(app.BAND_RANGE_6k_12k))
                              + app.outputBand_12k_14k * db2mag(app.gains(app.BAND_RANGE_12k_14k))
                              + app.outputBand_14k_16k * db2mag(app.gains(app.BAND_RANGE_14k_16k));
                              
                              
        end
        
        function updateOutputRespPlots(app)
            tSampling = 1/app.outputFreq;
            x = linspace(0,tSampling,length(app.outputAudio));
            plot(x, app.outputAudio, 'Parent', app.OutputTimeRespAxes);
            
            %outputAudioF = fftshift(fft(app.outputAudio));
            %y = linspace(0,app.outputFreq/length(outputAudioF), length(outputAudioF));
 
            outputAudioF = fft(app.outputAudio);
            outputAudioF = outputAudioF(1:length(app.outputAudio)/2+1);

            freqs = 0:app.outputFreq/length(app.outputAudio):app.outputFreq/2;
            plot(freqs, abs(outputAudioF), 'Parent', app.OutputFreqRespMagAxes);
            plot(freqs, unwrap(angle(outputAudioF)), 'Parent', app.OutputFreqRespPhaseAxes);
        end
        
        function updatePerBandRespPlots(app)
            
            outputBand = app.outputBand_0_170;
            
            switch app.perBandGraphBandRange
                
                case  app.BAND_RANGE_0_170
                    outputBand = app.outputBand_0_170;
                case  app.BAND_RANGE_170_310
                    outputBand = app.outputBand_170_310;
                case  app.BAND_RANGE_310_600
                    outputBand = app.outputBand_310_600;
                case app.BAND_RANGE_600_1k
                    outputBand = app.outputBand_600_1k;
                case  app.BAND_RANGE_1k_3k
                    outputBand = app.outputBand_1k_3k;
                case  app.BAND_RANGE_3k_6k
                    outputBand = app.outputBand_3k_6k;
                case  app.BAND_RANGE_6k_12k
                    outputBand = app.outputBand_6k_12k;
                case  app.BAND_RANGE_12k_14k
                    outputBand = app.outputBand_12k_14k;
                case  app.BAND_RANGE_14k_16k
                    outputBand = app.outputBand_14k_16k;                 
            end   
            
            tSampling = 1/app.outputFreq;
            x = linspace(0,tSampling,length(outputBand));
            plot(x, outputBand, 'Parent', app.PerBandTimeRespAxes);
            
            %outputBandF = fftshift(fft(outputBand));
            %y = linspace(0,app.outputFreq/length(outputBandF), length(outputBandF));
            %plot(y, outputBandF, 'Parent', app.PerBandFreqRespMagAxes);
            
            outputBandF = fft(outputBand);
            outputBandF = outputBandF(1:length(outputBand)/2+1);

            freqs = 0:app.outputFreq/length(outputBand):app.outputFreq/2;
            plot(freqs, abs(outputBandF), 'Parent', app.PerBandFreqRespMagAxes);
            plot(freqs, unwrap(angle(outputBandF)), 'Parent', app.PerBandFreqRespPhaseAxes);
        end

        function updateInputRespPlots(app)
            tSampling = 1/app.inputFreq;
            x = linspace(0,tSampling,length(app.inputAudio));
            disp(app.inputAudio);
            disp(app.inputFreq);
            plot(x, app.inputAudio, 'Parent', app.InputTimeRespAxes);
            
            %inputAudioF = fftshift(fft(app.inputAudio));
            %y = linspace(0,app.inputFreq/length(inputAudioF), length(inputAudioF));
            %plot(y, inputAudioF, 'Parent', app.InputFreqRespMagAxes);
            
            inputAudioF = fft(app.inputAudio);
            inputAudioF = inputAudioF(1:length(app.outputAudio)/2+1);
            
            freqs = 0:app.inputFreq/length(app.inputAudio):app.inputFreq/2;
            plot(freqs, abs(inputAudioF), 'Parent', app.InputFreqRespMagAxes);
            plot(freqs, unwrap(angle(inputAudioF)), 'Parent', app.InputFreqRespPhaseAxes);
        end
        
        function applyOutputSample(app)
                   
             switch app.outputSample
                 
                 case app.OUTPUT_SAMPLE_NORMAL
                    outputAudioTemp = app.outputAudio;
                 
                case app.OUTPUT_SAMPLE_HALVED
                    outputAudioTemp = decimate(app.outputAudio, 2);
                    
                case app.OUTPUT_SAMPLE_DOUBLED
                   outputAudioTemp = upsample(app.outputAudio, 2);
             end
             
             app.outputAudio = outputAudioTemp;
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: ApplyChangesButton
        function applyChanges(app, event)
            app.LoadingLabel.FontColor = 'red';
            createFilters(app);
            applyFilters(app);
            applyOutputSample(app);
            updateOutputRespPlots(app);
            updatePerBandRespPlots(app);
            app.LoadingLabel.FontColor = 'white';
        end

        % Value changed function: HzSlider
        function gainChanged_0_170(app, event)
            app.gains(app.BAND_RANGE_0_170) = app.HzSlider.Value;      
        end

        % Value changed function: KHzSlider_16
        function gainChanged_12k_14k(app, event)
             app.gains(app.BAND_RANGE_12k_14k) = app.KHzSlider_16.Value;            
        end

        % Value changed function: KHzSlider_17
        function gainChanged_14k_16k(app, event)
             app.gains(app.BAND_RANGE_14k_16k) = app.KHzSlider_17.Value;
        end

        % Value changed function: HzSlider_2
        function gainChanged_170_310(app, event)
             app.gains(app.BAND_RANGE_170_310) = app.HzSlider_2.Value;            
        end

        % Value changed function: HzSlider_3
        function gainChanged_310_600(app, event)
             app.gains(app.BAND_RANGE_310_600) = app.HzSlider_3.Value;            
        end

        % Value changed function: KHzSlider_14
        function gainChanged_3k_6k(app, event)
             app.gains(app.BAND_RANGE_3k_6k) = app.KHzSlider_14.Value;            
        end

        % Value changed function: KHzSlider_11
        function gainChanged_600_1k(app, event)
             app.gains(app.BAND_RANGE_600_1k) = app.KHzSlider_11.Value;            
        end

        % Value changed function: KHzSlider_15
        function gainChanged_6k_12K(app, event)
             app.gains(app.BAND_RANGE_6k_12k) = app.KHzSlider_15.Value;            
        end

        % Value changed function: KHzSlider_13
        function gain_changed_1k_3K(app, event)
             app.gains(app.BAND_RANGE_1k_3k) = app.KHzSlider_13.Value;            
        end

        % Button pushed function: BrowseButton
        function onBrowseInputClicked(app, event)
            [app.inputFileName, app.inputFilePath] = uigetfile({'*.wav*'}, 'Select Input Audio File');
            path = strcat(app.inputFilePath, app.inputFileName);
            
            app.LoadingLabel.FontColor = 'red';
            [app.inputAudio, app.inputFreq] = audioread(path);
            app.outputAudio = app.inputAudio;
            app.outputFreq = app.inputFreq;
            app.OutputSamplingFrequencyEditField.Value = app.inputFreq;
            app.InputSamplingFrequencyValueLabel.Text = int2str(app.inputFreq);
            
            app.OutputSampleRateDropDown.Enable = 'on';
            
            updateInputRespPlots(app);
            app.LoadingLabel.FontColor = 'white';
        end

        % Value changed function: FilterTypeDropDown
        function onFilterTypeChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            
            switch value
               
                 case app.FIR_HAMMING_S
                     app.currentFilterType = app.FIR_HAMMING;
                     
                 case app.FIR_RECTANGULAR_S
                     app.currentFilterType = app.FIR_RECTANGULAR;
                     
                 case app.FIR_EQUIRIPPLE_S
                     app.currentFilterType = app.FIR_EQUIRIPPLE;
                     
                 case app.FIR_LEAST_SQUARE_S
                     app.currentFilterType = app.FIR_LEAST_SQUARE;
                     
                 case app.IIR_BUTTERWORTH_S
                     app.currentFilterType = app.IIR_BUTTERWORTH;          
            end
        end

        % Value changed function: OutputSampleRateDropDown
        function onOutputSampleRateChanged(app, event)
            value = app.OutputSampleRateDropDown.Value;
            
            switch value
                case app.OUTPUT_SAMPLE_NORMAL_S
                    app.outputSample = app.OUTPUT_SAMPLE_NORMAL;
                    app.OutputSamplingFrequencyEditField.Value = app.inputFreq
                    app.outputFreq = app.inputFreq
                    
                case app.OUTPUT_SAMPLE_HALVED_S
                    app.outputSample = app.OUTPUT_SAMPLE_HALVED;
                    app.OutputSamplingFrequencyEditField.Value = app.inputFreq * 0.5
                    app.outputFreq = app.inputFreq * 0.5
                    
                case app.OUTPUT_SAMPLE_DOUBLED_S
                    app.outputSample = app.OUTPUT_SAMPLE_DOUBLED;
                    app.OutputSamplingFrequencyEditField.Value = app.inputFreq * 2
                    app.outputFreq = app.inputFreq * 2
                    
            end
        end

        % Button pushed function: PlayStopInputButton
        function onPlayInputClicked(app, event)
            clear sound;
            if (app.soundPlaying == 0)
                app.soundPlaying = 1;
                sound(app.inputAudio, app.inputFreq);
            else
                app.soundPlaying = 0;
            end
        end

        % Button pushed function: PlayStopOutputButton
        function onPlayOutputClicked(app, event)
            clear sound;
            if (app.soundPlaying == 0)
                app.soundPlaying = 1;
                sound(app.outputAudio, app.outputFreq);
            else
                app.soundPlaying = 0;
            end
        end

        % Button pushed function: SaveOutputButton
        function onSaveOutputClicked(app, event)
            app.outputFileName = strcat(app.inputFileName, "_filtered");
            app.outputFilePath = app.inputFilePath;
            audiowrite(app.outputFileName, app.outputAudio, app.outputFreq);
        end

        % Value changed function: OutputSamplingFrequencyEditField
        function outputFreqChanged(app, event)
            app.outputFreq = app.OutputSamplingFrequencyEditField.Value;            
        end

        % Value changed function: PerBandRespBandSelector
        function perBandRespBandSelectorChanged(app, event)
            value = app.PerBandRespBandSelector.Value;
            
            switch value  
                case  app.BAND_RANGE_0_170_S
                    app.perBandGraphBandRange = app.BAND_RANGE_0_170;
                    
                case  app.BAND_RANGE_170_310_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_170_310;
                    
                case  app.BAND_RANGE_310_600_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_310_600;
                    
                case app.BAND_RANGE_600_1k_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_600_1k;
                    
                case  app.BAND_RANGE_1k_3k_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_1k_3k;
                    
                case  app.BAND_RANGE_3k_6k_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_3k_6k;
                    
                case  app.BAND_RANGE_6k_12k_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_6k_12k;
                    
                case  app.BAND_RANGE_12k_14k_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_12k_14k;
                    
                case  app.BAND_RANGE_14k_16k_S
                    app.perBandGraphBandRange =  app.BAND_RANGE_14k_16k;
            end
            
           updatePerBandRespPlots(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioEqualizerUIFigure = uifigure;
            app.AudioEqualizerUIFigure.Name = 'Audio Equalizer';
            app.FilterTypeDropDownLabel = uilabel(app.AudioEqualizerUIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.AudioEqualizerUIFigure);
            app.FilterTypeDropDown.Items = {'FIR Hamming', 'FIR Rectangular', 'FIR Equiripple', 'FIR Least Square', 'IIR Butterworth'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @onFilterTypeChanged, true);
            app.FilterTypeDropDown.Value = 'FIR Hamming';
            app.BrowseButton = uibutton(app.AudioEqualizerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @onBrowseInputClicked, true);
            app.BrowseButton.Text = 'Browse ';
            app.SaveOutputButton = uibutton(app.AudioEqualizerUIFigure, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @onSaveOutputClicked, true);
            app.SaveOutputButton.Text = 'Save Output';
            app.OutputSamplingFrequencyEditFieldLabel = uilabel(app.AudioEqualizerUIFigure);
            app.OutputSamplingFrequencyEditFieldLabel.Text = 'Output Sampling Frequency';
            app.OutputSamplingFrequencyEditField = uieditfield(app.AudioEqualizerUIFigure, 'numeric');
            app.OutputSamplingFrequencyEditField.Limits = [0 Inf];
            app.OutputSamplingFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.OutputSamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @outputFreqChanged, true);
            app.OutputSamplingFrequencyEditField.Value = 44100;
            app.PlayStopInputButton = uibutton(app.AudioEqualizerUIFigure, 'push');
            app.PlayStopInputButton.ButtonPushedFcn = createCallbackFcn(app, @onPlayInputClicked, true);
            app.PlayStopInputButton.Text = 'Play/Stop Input';
            app.PlayStopOutputButton = uibutton(app.AudioEqualizerUIFigure, 'push');
            app.PlayStopOutputButton.ButtonPushedFcn = createCallbackFcn(app, @onPlayOutputClicked, true);
            app.PlayStopOutputButton.Text = 'Play/Stop Output';
            app.HzLabel_7 = uilabel(app.AudioEqualizerUIFigure);
            app.HzLabel_7.Text = '0 -  170 Hz   ';
            app.HzSlider = uislider(app.AudioEqualizerUIFigure);
            app.HzSlider.Limits = [-12 12];
            app.HzSlider.MajorTickLabels = {'-12 dB', '', '', '', '', '', '', '', '12 dB'};
            app.HzSlider.ValueChangedFcn = createCallbackFcn(app, @gainChanged_0_170, true);
            app.HzSlider.MinorTicks = [];
            app.HzLabel = uilabel(app.AudioEqualizerUIFigure);
            app.HzLabel.Text = '170 - 310 Hz';
            app.HzSlider_2 = uislider(app.AudioEqualizerUIFigure);
            app.HzSlider_2.Limits = [-12 12];
            app.HzSlider_2.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @gainChanged_170_310, true);
            app.HzSlider_2.MinorTicks = [];
            app.KHzLabel = uilabel(app.AudioEqualizerUIFigure);
            app.KHzLabel.Text = ' 600 - 1  KHz';
            app.KHzSlider_11 = uislider(app.AudioEqualizerUIFigure);
            app.KHzSlider_11.Limits = [-12 12];
            app.KHzSlider_11.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.KHzSlider_11.ValueChangedFcn = createCallbackFcn(app, @gainChanged_600_1k, true);
            app.KHzSlider_11.MinorTicks = [];
            app.HzSlider_3Label = uilabel(app.AudioEqualizerUIFigure);
            app.HzSlider_3Label.Text = '310 - 600 Hz';
            app.HzSlider_3 = uislider(app.AudioEqualizerUIFigure);
            app.HzSlider_3.Limits = [-12 12];
            app.HzSlider_3.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @gainChanged_310_600, true);
            app.HzSlider_3.MinorTicks = [];
            app.KHzLabel_2 = uilabel(app.AudioEqualizerUIFigure);
            app.KHzLabel_2.Text = '  1 - 3 KHz  ';
            app.KHzSlider_13 = uislider(app.AudioEqualizerUIFigure);
            app.KHzSlider_13.Limits = [-12 12];
            app.KHzSlider_13.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.KHzSlider_13.ValueChangedFcn = createCallbackFcn(app, @gain_changed_1k_3K, true);
            app.KHzSlider_13.MinorTicks = [];
            app.KHzLabel_3 = uilabel(app.AudioEqualizerUIFigure);
            app.KHzLabel_3.Text = '  3 - 6 KHz  ';
            app.KHzSlider_14 = uislider(app.AudioEqualizerUIFigure);
            app.KHzSlider_14.Limits = [-12 12];
            app.KHzSlider_14.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.KHzSlider_14.ValueChangedFcn = createCallbackFcn(app, @gainChanged_3k_6k, true);
            app.KHzSlider_14.MinorTicks = [];
            app.KHzLabel_4 = uilabel(app.AudioEqualizerUIFigure);
            app.KHzLabel_4.Text = '  6 - 12 KHz  ';
            app.KHzSlider_15 = uislider(app.AudioEqualizerUIFigure);
            app.KHzSlider_15.Limits = [-12 12];
            app.KHzSlider_15.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.KHzSlider_15.ValueChangedFcn = createCallbackFcn(app, @gainChanged_6k_12K, true);
            app.KHzSlider_15.MinorTicks = [];
            app.KHzLabel_5 = uilabel(app.AudioEqualizerUIFigure);
            app.KHzLabel_5.Text = ' 12 - 14 KHz ';
            app.KHzSlider_16 = uislider(app.AudioEqualizerUIFigure);
            app.KHzSlider_16.Limits = [-12 12];
            app.KHzSlider_16.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.KHzSlider_16.ValueChangedFcn = createCallbackFcn(app, @gainChanged_12k_14k, true);
            app.KHzSlider_16.MinorTicks = [];
            app.KHzLabel_6 = uilabel(app.AudioEqualizerUIFigure);
            app.KHzLabel_6.Text = ' 14 - 16 KHz ';
            app.KHzSlider_17 = uislider(app.AudioEqualizerUIFigure);
            app.KHzSlider_17.Limits = [-12 12];
            app.KHzSlider_17.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.KHzSlider_17.ValueChangedFcn = createCallbackFcn(app, @gainChanged_14k_16k, true);
            app.KHzSlider_17.MinorTicks = [];
            app.TabGroup = uitabgroup(app.AudioEqualizerUIFigure);
            app.OutputResponseTab = uitab(app.TabGroup);
            app.OutputResponseTab.Title = 'Output Response';
            app.OutputTimeRespAxes = uiaxes(app.OutputResponseTab);
            title(app.OutputTimeRespAxes, 'Time Response')
            xlabel(app.OutputTimeRespAxes, 'Time (t)')
            ylabel(app.OutputTimeRespAxes, 'F(t)')
            app.OutputFreqRespMagAxes = uiaxes(app.OutputResponseTab);
            title(app.OutputFreqRespMagAxes, 'Frequncy Response Magnitude')
            xlabel(app.OutputFreqRespMagAxes, 'Frequency (w)')
            ylabel(app.OutputFreqRespMagAxes, 'F(w)')
            app.OutputFreqRespPhaseAxes = uiaxes(app.OutputResponseTab);
            title(app.OutputFreqRespPhaseAxes, 'Frequncy Response Phase')
            xlabel(app.OutputFreqRespPhaseAxes, 'Frequency (w)')
            ylabel(app.OutputFreqRespPhaseAxes, 'Φ(w)')
            app.PerBandResponseTab = uitab(app.TabGroup);
            app.PerBandResponseTab.Title = 'Per Band Response';
            app.BandDropDownLabel = uilabel(app.PerBandResponseTab);
            app.BandDropDownLabel.Text = 'Band';
            app.PerBandRespBandSelector = uidropdown(app.PerBandResponseTab);
            app.PerBandRespBandSelector.Items = {'0 - 170 Hz', '170 - 310 Hz', '310 - 600 Hz', '600 - 1000 Hz', '1 - 3 KHz', '3 - 6 KHz', '6 - 12 KHz', '12 - 14 KHz', '14 - 16 KHz'};
            app.PerBandRespBandSelector.ValueChangedFcn = createCallbackFcn(app, @perBandRespBandSelectorChanged, true);
            app.PerBandRespBandSelector.Value = '0 - 170 Hz';
            app.PerBandTimeRespAxes = uiaxes(app.PerBandResponseTab);
            title(app.PerBandTimeRespAxes, 'Time Response')
            xlabel(app.PerBandTimeRespAxes, 'Time (t)')
            ylabel(app.PerBandTimeRespAxes, 'F(t)')
            app.PerBandFreqRespMagAxes = uiaxes(app.PerBandResponseTab);
            title(app.PerBandFreqRespMagAxes, 'Frequency Response Magnitude')
            xlabel(app.PerBandFreqRespMagAxes, 'Frequency (w)')
            ylabel(app.PerBandFreqRespMagAxes, 'F(w)')
            app.PerBandFreqRespPhaseAxes = uiaxes(app.PerBandResponseTab);
            title(app.PerBandFreqRespPhaseAxes, 'Frequency Response Phase')
            xlabel(app.PerBandFreqRespPhaseAxes, 'Frequency (w)')
            ylabel(app.PerBandFreqRespPhaseAxes, 'Φ(w)')
            app.InputResponseTab = uitab(app.TabGroup);
            app.InputResponseTab.Title = 'Input Response';
            app.InputTimeRespAxes = uiaxes(app.InputResponseTab);
            title(app.InputTimeRespAxes, 'Time Response')
            xlabel(app.InputTimeRespAxes, 'Time (t)')
            ylabel(app.InputTimeRespAxes, 'F(t)')
            app.InputFreqRespMagAxes = uiaxes(app.InputResponseTab);
            title(app.InputFreqRespMagAxes, 'Frequency Response Magnitude')
            xlabel(app.InputFreqRespMagAxes, 'Frequency (w)')
            ylabel(app.InputFreqRespMagAxes, 'F(w)')
            app.InputFreqRespPhaseAxes = uiaxes(app.InputResponseTab);
            title(app.InputFreqRespPhaseAxes, 'Frequency Response Phase')
            xlabel(app.InputFreqRespPhaseAxes, 'Frequency (w)')
            ylabel(app.InputFreqRespPhaseAxes, 'Φ(w)')
            app.ApplyChangesButton = uibutton(app.AudioEqualizerUIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @applyChanges, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.InputSamplingFrequencyLabel = uilabel(app.AudioEqualizerUIFigure);
            app.InputSamplingFrequencyLabel.Text = 'Input Sampling Frequency';
            app.InputSamplingFrequencyValueLabel = uilabel(app.AudioEqualizerUIFigure);
            app.InputSamplingFrequencyValueLabel.Text = '44100';
            app.OutputSampleRateDropDownLabel = uilabel(app.AudioEqualizerUIFigure);
            app.OutputSampleRateDropDownLabel.Text = 'Output Sample Rate';
            app.OutputSampleRateDropDown = uidropdown(app.AudioEqualizerUIFigure);
            app.OutputSampleRateDropDown.Items = {'Normal', 'Halved', 'Doubled'};
            app.OutputSampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @onOutputSampleRateChanged, true);
            app.OutputSampleRateDropDown.Enable = 'off';
            app.OutputSampleRateDropDown.Value = 'Normal';
            app.LoadingLabel = uilabel(app.AudioEqualizerUIFigure);
            app.LoadingLabel.Text = 'Loading..';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioEqualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioEqualizerUIFigure)
        end
    end
end